classdef PolarimetryPlus < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PolarimetryAnalysisUIFigure     matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        Image                           matlab.ui.control.Image
        PolarimetryMethod               matlab.ui.control.DropDown
        CloseFiguresButton              matlab.ui.control.Button
        PolarimetryAnalysisLogo         matlab.ui.control.Button
        DropDown                        matlab.ui.control.DropDown
        DownloadFolderButton            matlab.ui.control.Button
        AnalysisButton                  matlab.ui.control.Button
        AddROIButton                    matlab.ui.control.Button
        DownloadFileButton              matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        FluorescenceTab                 matlab.ui.container.Tab
        Reload                          matlab.ui.control.Button
        NameOfFile                      matlab.ui.control.EditField
        ContrastFluoButton              matlab.ui.control.Button
        DetAngle                        matlab.ui.control.Button
        MaximumIntensityEditField       matlab.ui.control.NumericEditField
        MaximumIntensityEditFieldLabel  matlab.ui.control.Label
        ContrastSlider                  matlab.ui.control.Slider
        UIAxesFluo                      matlab.ui.control.UIAxes
        ThresholdingMaskTab             matlab.ui.container.Tab
        ContrastThrshldButton           matlab.ui.control.Button
        ContrastThrshldSlider           matlab.ui.control.Slider
        ExportMask                      matlab.ui.control.Button
        NoBackgroundButton              matlab.ui.control.Button
        TransparencyLabel               matlab.ui.control.Label
        TransparencySlider              matlab.ui.control.Slider
        ILowImaxSlider                  matlab.ui.control.Slider
        ILowImaxLabel                   matlab.ui.control.Label
        ILowEditField                   matlab.ui.control.NumericEditField
        ILowEditFieldLabel              matlab.ui.control.Label
        UIAxesThrshld                   matlab.ui.control.UIAxes
        OptionsTab                      matlab.ui.container.Tab
        Switchminmax                    matlab.ui.control.Switch
        SwitchminmaxLabel               matlab.ui.control.Label
        PlotoptionsPanel                matlab.ui.container.Panel
        ShowIndividualFitCheckBox       matlab.ui.control.CheckBox
        ColormapThrshld                 matlab.ui.control.DropDown
        ColormapThresholdingDropDownLabel  matlab.ui.control.Label
        PixelsPerStickSpinner           matlab.ui.control.Spinner
        pixelsperstickLabel             matlab.ui.control.Label
        AddAxesCheckBox                 matlab.ui.control.CheckBox
        UITableVariable                 matlab.ui.control.Table
        UITableDisplay                  matlab.ui.control.Table
        DeleteAllButton                 matlab.ui.control.Button
        perROISwitch                    matlab.ui.control.Switch
        perROILabel                     matlab.ui.control.Label
        UITableSaveExtension            matlab.ui.control.Table
        AdvancedTab                     matlab.ui.container.Tab
        Switchoffset                    matlab.ui.control.Switch
        SwitchoffsetLabel               matlab.ui.control.Label
        DarkPanel                       matlab.ui.container.Panel
        CalculatedDarkValueEditField    matlab.ui.control.NumericEditField
        UserDarkValueEditField          matlab.ui.control.NumericEditField
        UserdarkvalueEditFieldLabel     matlab.ui.control.Label
        CalculatedDarkValueCheckBox     matlab.ui.control.CheckBox
        DiskConePanel                   matlab.ui.container.Panel
        SelectDiskCone                  matlab.ui.control.DropDown
        NameOfDiskCone                  matlab.ui.control.EditField
        DisplayConeButton               matlab.ui.control.Button
        RotationPanel                   matlab.ui.container.Panel
        FigureRotationEditField         matlab.ui.control.NumericEditField
        FigureLabel                     matlab.ui.control.Label
        StickRotationEditField          matlab.ui.control.NumericEditField
        StickLabel                      matlab.ui.control.Label
        OffsetAngleDegEditField         matlab.ui.control.NumericEditField
        OffsetangledegEditFieldLabel    matlab.ui.control.Label
        RemovebackgroundPanel           matlab.ui.container.Panel
        NoiseremovallevelEditField      matlab.ui.control.NumericEditField
        NoiseremovallevelEditFieldLabel  matlab.ui.control.Label
        NoiseHeightEditField            matlab.ui.control.NumericEditField
        NoiseheightEditFieldLabel       matlab.ui.control.Label
        NoiseWidthEditField             matlab.ui.control.NumericEditField
        NoisewidthEditFieldLabel        matlab.ui.control.Label
        ClickBackgroundButton           matlab.ui.control.Button
        NoiseFactorEditField            matlab.ui.control.NumericEditField
        NoisefactorEditFieldLabel       matlab.ui.control.Label
        BinningPanel                    matlab.ui.container.Panel
        BinHeightEditField              matlab.ui.control.NumericEditField
        BinheightEditFieldLabel         matlab.ui.control.Label
        BinWidthEditField               matlab.ui.control.NumericEditField
        BinwidthEditFieldLabel          matlab.ui.control.Label
        AboutTab                        matlab.ui.container.Tab
        CheckWebsite                    matlab.ui.control.Button
        InfoAbout                       matlab.ui.control.TextArea
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        I_tot % Mean intensity
        n_angle % number of angles per stack
        ROI % ROI map with ROI index numbers
        int_roi % index of ROIs
        Im % intensities
        P % parameters
        Folder % folder name for batch
        MaskFolder % folder name for masks
        Sum_cor % mean intensity 
        AdvanceFile % automatic or manual advance for batch
        path % path of folder
        fs % file stack names for batch
        Mask % Mask 
        Patch % Patch objects
        SingleFile % Single file or folder
        ROI_ILow % same as ROI; values of ILow instead of the ROI index numbers
        ClickbackgroundChanged % if true, change of Noise_tot
        ClickbackgroundCoord % Coordinates (x1,x2,y1,y2) of the patch to compute Noise_tot
        ImageThrshld % Label for image Thresholding 
        Variables % Structure of the variables to be displayed (rho, psi, s2, s4, etc...) 
    end
    
    methods (Access = private)
        
        function Represent_Fluo(app, Itot, DrawNow)
            if ~DrawNow
                XLimFluo = app.UIAxesFluo.XLim;
                YLimFluo = app.UIAxesFluo.YLim;
            end
            im = imadjust(imsharpen(Itot/max(Itot(:))),[0 1],[0 1],app.ContrastSlider.Value);
            if app.FigureRotationEditField.Value~=0
                im = imrotate(im,app.FigureRotationEditField.Value,'bilinear','crop');
            end
            imagesc(app.UIAxesFluo,im);
            axis(app.UIAxesFluo,'image');
            colormap(app.UIAxesFluo,gray); 
            if ~DrawNow 
                app.UIAxesFluo.XLim = XLimFluo;
                app.UIAxesFluo.YLim = YLimFluo;
            end
            [ImageWidth,ImageHeight] = size(Itot);
            if app.int_roi~=0 && ~isempty(app.Patch.XData)
                for it = 1:app.int_roi
                    prot = patch(app.UIAxesFluo,'XData',app.Patch.XData{it},'YData',app.Patch.YData{it},'FaceAlpha',0,'EdgeColor','w');
                    rotate(prot,[0 0 1],-app.FigureRotationEditField.Value,[ImageHeight/2,ImageWidth/2,0]);
                    htext = text(app.UIAxesFluo,app.Patch.XData{it}(1),app.Patch.YData{it}(1),num2str(it),'FontSize',20,'Color','w');
                    rotate(htext,[0 0 1],-app.FigureRotationEditField.Value,[ImageHeight/2,ImageWidth/2,0]);
                end
            end
        end
        
        function [Sumcor, Itot] = Define_I_tot(app, Im)
            Dark = ComputeDark(app, Im);
            app.CalculatedDarkValueEditField.Value = Dark;
            if ~app.CalculatedDarkValueCheckBox.Value
                Dark = app.UserDarkValueEditField.Value;
            end    
            Im_cor = (Im-Dark).*(Im>=Dark);       
            Sumcor = sum(Im_cor,3);
            if app.BinHeightEditField.Value~=1 || app.BinWidthEditField.Value~=1
                BIN = ones(app.BinWidthEditField.Value,app.BinHeightEditField.Value);                                                     
                Itot = convn(Sumcor,BIN)/app.BinWidthEditField.Value/app.BinHeightEditField.Value; 
            else
                Itot = Sumcor;
            end
            app.MaximumIntensityEditField.Value = round(max(Itot(:)));
        end
        
        function PolarimetryAnalysis(app, Im, P, Mask, ROI)
            Chi2Threshold = 500; 
            [file.folder,file.name,~] = fileparts(P.filename);
            [ImageWidth,ImageHeight,ImageAngle] = size(Im);
            Angle1D = linspace(0,pi,ImageAngle+1)+deg2rad(app.OffsetAngleDegEditField.Value);
            Angle3D = reshape(Angle1D,1,1,[]);
            E2 = exp(2i*Angle3D);
            if app.PolarimetryMethod.Value>=1
                E4 = E2.^2;
            end
            if app.CalculatedDarkValueCheckBox.Value
                Dark = app.CalculatedDarkValueEditField.Value;
            else
                Dark = app.UserDarkValueEditField.Value;
            end
            Im_rel = (Im-Dark).*(Im>=Dark)-P.Noise_tot*app.NoiseFactorEditField.Value;
            Im_rel = Im_rel.*(Im_rel>=0); 
            if app.PolarimetryMethod.Value==1
                field = padarray(sqrt(Im_rel),[0 0 1],'circular','post');
            else
                field = padarray(Im_rel,[0 0 1],'circular','post');
            end
            if app.BinHeightEditField.Value~=1 || app.BinWidthEditField.Value~=1
                BIN = ones(app.BinWidthEditField.Value,app.BinHeightEditField.Value);
                field = convn(field,BIN);        
            end
            A0 = trapz(Angle1D,field,3)/pi;
            A2 = 2*trapz(Angle1D,field.*E2,3)/pi;
            if app.PolarimetryMethod.Value>=1
                A4 = 2*trapz(Angle1D,field.*E4,3)/pi;
            end
            field_fit = A0+real(A2.*conj(E2));
            if app.PolarimetryMethod.Value>=1
                field_fit = field_fit+real(A4.*conj(E4));
            end
            Chi2 = mean((field-field_fit).^2./field_fit,3);
            A2n = real(A2)./A0;
            B2n = imag(A2)./A0;
            if app.PolarimetryMethod.Value>=1
                A4n = real(A4)./A0;
                B4n = imag(A4)./A0;
            end
            Rho_.Name = 'Rho';
            Rho_.LaTeX = '$\rho$';
            Rho_.Value = NaN(size(A0));
            Rho_.Min = app.UITableVariable.Data{1,2};
            Rho_.Max = app.UITableVariable.Data{1,3};
            Rho_.Display = app.UITableVariable.Data{1,1};
            Rho_.Colormap = hsv;
            if app.PolarimetryMethod.Value==0
                Psi_.Value = NaN(size(A0));
                [X,Y] = find(((ROI>0).*Mask~=0).*(abs(A2n)<1).*(abs(B2n)<1).*(Chi2<=Chi2Threshold).*(Chi2>0));
                ind = sub2ind(size(A0),X,Y);
                Int = A0(ind);
                Rho_DC = 90-app.P.RoTest(sub2ind(size(app.P.RoTest), 1+round((A2n(ind)+1)*(app.P.NbMapValues-1)/2),...
                    1+round((B2n(ind)+1)*(app.P.NbMapValues-1)/2)));
                Psi_DC = app.P.PsiTest(sub2ind(size(app.P.RoTest), 1+round((A2n(ind)+1)*(app.P.NbMapValues-1)/2),...
                    1+round((B2n(ind)+1)*(app.P.NbMapValues-1)/2))); 
                Filter = find((~isnan(Rho_DC))&(~isnan(Psi_DC)));
                ind = sub2ind(size(A0),X(Filter),Y(Filter));
                Rho = wrapTo360(2*(Rho_DC(Filter)+app.StickRotationEditField.Value))/2;
                Psi = Psi_DC(Filter);
                Rho_.Value(ind) = Rho;
                Psi_.Name = 'Psi';
                Psi_.LaTeX = '$\psi$';
                Psi_.Value(ind) = Psi;
                Psi_.Min = app.UITableVariable.Data{2,2};
                Psi_.Max = app.UITableVariable.Data{2,3};
                Psi_.Display = app.UITableVariable.Data{2,1};
                Psi_.Colormap = jet;
                Int = Int(Filter);
                X = X(Filter);
                Y = Y(Filter); 
            else 
                [X,Y] = find(((ROI>0).*Mask~=0).*(abs(A2n)<1).*(abs(B2n)<1).*(abs(A4n)<1).*(abs(B4n)<1).*(Chi2<=Chi2Threshold).*(Chi2>0));
                ind = sub2ind(size(A0),X,Y);
                Int = A0(ind);
                Rho = 0.5*atan2d(B2n./A2n);
                Rho = wrapTo360(2*(Rho+app.StickRotationEditField.Value))/2;
                Rho_.Value(ind) = Rho(ind);
                if app.PolarimetryMethod.Value==3
                    S_SHG = -0.5*(sqrt(A4n.^2+B4n.^2)-sqrt(A2n.^2+B2n.^2))./(sqrt(A4n.^2+B4n.^2)+sqrt(A2n.^2+B2n.^2))-0.65;
                    S_SHG_.Name = 'S_SHG';
                    S_SHG_.LaTeX = '$S_\mathrm{SHG}$';
                    S_SHG_.Value = NaN(size(A0));
                    S_SHG_.Value(ind) = S_SHG(ind);
                    S_SHG_.Min = app.UITableVariable.Data{2,2};
                    S_SHG_.Max = app.UITableVariable.Data{2,3};
                    S_SHG_.Display = app.UITableVariable.Data{2,1};
                    S_SHG_.Colormap = jet;
                else
                    S2 = 1.5*sqrt(A2n.^2+B2n.^2);
                    Phi4 = 0.25*atan2d(B4n./A4n);
                    S4 = 6*sqrt(A4n.^2+B4n.^2).*cosd(4*(Phi4-Rho));
                    S2_.Name = 'S2';
                    S2_.LaTeX = '$S_2$';
                    S2_.Value = NaN(size(A0));
                    S2_.Value(ind) = S2(ind);
                    S2_.Min = app.UITableVariable.Data{2,2};
                    S2_.Max = app.UITableVariable.Data{2,3};
                    S2_.Display = app.UITableVariable.Data{2,1};
                    S2_.Colormap = jet;
                    S4_.Name = 'S4';
                    S4_.LaTeX = '$S_4$';
                    S4_.Value = NaN(size(A0));
                    S4_.Value(ind) = S4(ind);             
                    S4_.Min = -1;
                    S4_.Max = 1;
                    S4_.Display = app.UITableVariable.Data{3,1};
                    S4_.Colormap = jet;
                end
            end
            Int_ = NaN(size(A0));
            X_ = NaN(size(A0));
            Y_ = NaN(size(A0));
            Chi2_ = NaN(size(A0));
            Int_(ind) = Int;
            X_(ind) = X;
            Y_(ind) = Y;
            Chi2_(ind) = Chi2(ind);
            %% Background fluo for Composite and Stick figures
            imbackground = imadjust(imsharpen(app.I_tot/max(app.I_tot(:))),[0 1],[0 1],app.ContrastSlider.Value);
            if app.FigureRotationEditField.Value~=0
                imbackground = imrotate(imbackground,app.FigureRotationEditField.Value,'bilinear','crop');
            end
            ImageSize = [ImageWidth ImageHeight];
            %% Generation of the plots
            figComposite = Composite(app,Rho_,imbackground,true,file);
            Sticks(app,Rho_,imbackground,Rho_.Value,true,file,ImageSize);
            Histos(app,Rho_,ROI,true,file);
            if app.PolarimetryMethod.Value==0
                Composite(app,Psi_,imbackground,false,file);
                Sticks(app,Psi_,imbackground,Rho_.Value,false,file,ImageSize);
                Histos(app,Psi_,ROI,false,file);
            elseif app.PolarimetryMethod.Value==1 || app.PolarimetryMethod.Value==2 || app.PolarimetryMethod.Value==4
                Composite(app,S2_,imbackground,false,file);
                Sticks(app,S2_,imbackground,Rho_.Value,false,file,ImageSize);
                Histos(app,S2_,ROI,false,file);
                Composite(app,S4_,imbackground,false,file);             
                Sticks(app,S4_,imbackground,Rho_.Value,false,file,ImageSize);              
                Histos(app,S4_,ROI,false,file);
            elseif app.PolarimetryMethod.Value==3
                Composite(app,S_SHG_,imbackground,false,file);
                Sticks(app,S_SHG_,imbackground,Rho_.Value,false,file,ImageSize);
                Histos(app,S_SHG_,ROI,false,file);
            end
            %% Polar response visualization at a pixel
            Show = 'Yes';
            if app.ShowIndividualFitCheckBox.Value
                selectionOK = uiconfirm(app.PolarimetryAnalysisUIFigure,{'(1) Zoom into the region of interest, then press OK',...
                   '(2) Click on the pixel where you want to see the polar plots'},'Polar response visualization at a pixel',...
                   'Icon','Icons/round_timeline_black_48dp.png');
                while strcmp(Show,'Yes') && strcmp(selectionOK,'OK')
                    figure(figComposite);
                    [y,x] = ginput(1);
                    x = round(x);
                    y = round(y);
                    signal = field(x,y,:);
                    signal_fit = field_fit(x,y,:);
                    alpha = linspace(0,180,ImageAngle+1);
                    rho = wrapTo360(2*(Rho_.Value(x,y)-app.FigureRotationEditField.Value))/2;
                    figure('name','Individual fit');
                    clf;
                    subplot(2,1,1);
                    plot(alpha,signal(:),'*',alpha,signal_fit(:),'-','LineWidth',2);
                    xlabel('$\alpha$','Interpreter','latex','FontSize',20); ylabel('counts','FontSize',20);
                    if app.PolarimetryMethod.Value==0
                        title_fig = ['$\rho =$ ' num2str(rho,'%.2f') ',   $\psi =$ ' num2str(Psi_.Value(x,y),'%.2f')];
                    elseif app.PolarimetryMethod.Value==1 || app.PolarimetryMethod.Value==2 || app.PolarimetryMethod.Value==4
                        title_fig = ['$\rho =$ ' num2str(rho,'%.2f') ',   $S_2 =$ ' num2str(S2_.Value(x,y),'%.2f')
                            ', $S_4 = $' num2str(S4_.Value(x,y),'%.2f')];
                    elseif app.PolarimetryMethod.Value==3
                        title_fig = ['$\rho =$ ' num2str(rho,'%.2f') ',   $S_\mathrm{SHG} =$ ' num2str(S_SHG_.Value(x,y),'%.2f')];
                    end
                    title(title_fig,'interpreter','latex','FontSize',20);
                    xlim([0 180]);
                    set(gca,'FontSize',20)
                    subplot(2,1,2);
                    plot(alpha,signal(:)-signal_fit(:),'+',alpha,2*sqrt(signal_fit(:)),'r-',alpha,-2*sqrt(signal_fit(:)),'r-','LineWidth',2);
                    xlabel('$\alpha$','Interpreter','latex','FontSize',20); ylabel('Residuals','FontSize',20);
                    title(['$\chi_2 =$ ' num2str(Chi2_(x,y),'%.2f') ',   $N_\mathrm{photons} =$ ' num2str(round(app.I_tot(x,y)))],'interpreter','latex','FontSize',20);
                    xlim([0 180]);
                    set(gca,'FontSize',20)
                    Show = uiconfirm(app.PolarimetryAnalysisUIFigure,'To see another data plot, change the region of interest and press Yes', ...
                        'Polar response visualization at a pixel','Options',{'Yes','No'},'DefaultOption',2,'Icon','Icons/round_timeline_black_48dp.png');
                end
            end 
            %% Saving to Microsoft Excel
            if app.UITableSaveExtension.Data{3}
                Tdata = [];
                if strcmp(app.perROISwitch.Value,'On')
                    for it = 1:max(ROI(:))
                        Rho = wrapTo360(2*(Rho_.Value(ROI==it & isfinite(Rho_.Value))-app.FigureRotationEditField.Value))/2;
                        avgCRho = mean(exp(-2i*deg2rad(Rho)));
                        meanCangle = rad2deg(wrapTo2Pi(2*(pi-angle(avgCRho)/2))/2);
                        DeltaRho = Rho-meanCangle;
                        DeltaRho = wrapTo360(2*DeltaRho)/2;
                        MeanDeltaRho = mean(DeltaRho);
                        MeanRho = wrapTo360(2*meanCangle)/2;
                        StdRho = std(DeltaRho);
                        Int = Int_(ROI==it & isfinite(Rho_.Value));
                        MeanInt = mean(Int);
                        TotalInt = MeanInt*app.n_angle;
                        StdInt = std(Int);
                        N = numel(Rho);
                        if app.PolarimetryMethod.Value==0
                            Psi = Psi_.Value(ROI==it & isfinite(Rho_.Value));
                            MeanPsi = mean(Psi);
                            StdPsi = std(Psi);
                            Results = [it,MeanRho,StdRho,MeanDeltaRho,MeanPsi,StdPsi,MeanInt,StdInt,TotalInt,N]; 
                        elseif app.PolarimetryMethod.Value==1 || app.PolarimetryMethod.Value==2 || app.PolarimetryMethod.Value==4
                            S2 = S2_.Value(ROI==it & isfinite(Rho_.Value));
                            MeanS2 = mean(S2);
                            StdS2 = std(S2);
                            S4 = S4_.Value(ROI==it & isfinite(Rho_.Value));
                            MeanS4 = mean(S4);
                            StdS4 = std(S4);
                            Results = [it,MeanRho,StdRho,MeanDeltaRho,MeanS2,StdS2,MeanS4,StdS4,MeanInt,StdInt,TotalInt,N];
                        elseif app.PolarimetryMethod.Value==3
                            S_SHG = S_SHG_.Value(ROI==it & isfinite(Rho_.Value));
                            MeanS_SHG = mean(S_SHG);
                            StdS_SHG = std(S_SHG);
                            Results = [it,MeanRho,StdRho,MeanDeltaRho,MeanS_SHG,StdS_SHG,MeanInt,StdInt,TotalInt,N];
                        end
                        Tdata = [Tdata;Results];
                    end
                else
                    Rho = wrapTo360(2*(Rho_.Value(ROI~=0 & isfinite(Rho_.Value))-app.FigureRotationEditField.Value))/2;
                    avgCRho = mean(exp(-2i*deg2rad(Rho)));
                    meanCangle = rad2deg(wrapTo2Pi(2*(pi-angle(avgCRho)/2))/2);
                    DeltaRho = Rho-meanCangle;
                    DeltaRho = wrapTo360(2*DeltaRho)/2;
                    MeanDeltaRho = mean(DeltaRho);
                    MeanRho = wrapTo360(2*meanCangle)/2;
                    StdRho = std(DeltaRho);
                    Int = Int_(ROI~=0 & isfinite(Rho_.Value));
                    MeanInt = mean(Int);
                    TotalInt = MeanInt*app.n_angle;
                    StdInt = std(Int);
                    N = numel(Rho);
                    if app.PolarimetryMethod.Value==0
                        Psi = Psi_.Value(ROI~=0 & isfinite(Rho_.Value));
                        MeanPsi = mean(Psi);
                        StdPsi = std(Psi);
                        Results = [1,MeanRho,StdRho,MeanDeltaRho,MeanPsi,StdPsi,MeanInt,StdInt,TotalInt,N]; 
                    elseif app.PolarimetryMethod.Value==1 || app.PolarimetryMethod.Value==2 || app.PolarimetryMethod.Value==4
                        S2 = S2_.Value(ROI~=0 & isfinite(Rho_.Value));
                        MeanS2 = mean(S2);
                        StdS2 = std(S2);
                        S4 = S4_.Value(ROI~=0 & isfinite(Rho_.Value));
                        MeanS4 = mean(S4);
                        StdS4 = std(S4);
                        Results = [1,MeanRho,StdRho,MeanDeltaRho,MeanS2,StdS2,MeanS4,StdS4,MeanInt,StdInt,TotalInt,N];
                    elseif app.PolarimetryMethod.Value==3
                        S_SHG = S_SHG_.Value(ROI~=0 & isfinite(Rho_.Value));
                        MeanS_SHG = mean(S_SHG);
                        StdS_SHG = std(S_SHG);
                        Results = [1,MeanRho,StdRho,MeanDeltaRho,MeanS_SHG,StdS_SHG,MeanInt,StdInt,TotalInt,N];
                    end
                    Tdata = [Tdata;Results];
                end
                if app.PolarimetryMethod.Value==0
                    T = array2table(Tdata,'VariableNames',...
                        {'ROI','MeanRho','StdRho','MeanDeltaRho','MeanPsi','StdPsi','MeanInt','StdInt','TotalInt','N'});
                    T = [repmat({matlab.lang.makeValidName(file.name)},size(Tdata,1),1) T repmat({matlab.lang.makeValidName(app.NameOfDiskCone.Value)},size(Tdata,1),1)];
                    T.Properties.VariableNames{'Var12'} = 'Disk Cone';
                elseif app.PolarimetryMethod.Value==1 || app.PolarimetryMethod.Value==2 || app.PolarimetryMethod.Value==4
                    T = array2table(Tdata,'VariableNames',...
                        {'ROI','MeanRho','StdRho','MeanDeltaRho','MeanS2','StdS2','MeanS4','StdS4','MeanInt','StdInt','TotalInt','N'});
                    T = [repmat({matlab.lang.makeValidName(file.name)},size(Tdata,1),1) T];
                elseif app.PolarimetryMethod.Value==3
                    T = array2table(Tdata,'VariableNames',...
                        {'ROI','MeanRho','StdRho','MeanDeltaRho','MeanS_SHG','StdS_SHG','MeanInt','StdInt','TotalInt','N'});
                    T = [repmat({matlab.lang.makeValidName(file.name)},size(Tdata,1),1) T];
                end
                T.Properties.VariableNames{'Var1'} = 'File';
                if app.SingleFile
                    writetable(T,[file.folder filesep file.name '_Stats.xlsx'],'Sheet',1,'WriteMode','Append','WriteRowNames',true);
                else
                    writetable(T,[file.folder filesep 'Folder_Stats.xlsx'],'Sheet',1,'WriteMode','Append','WriteRowNames',true);
                end
            end
            %% Saving to Matlab data files
            if app.UITableSaveExtension.Data{2}
                if strcmp(app.perROISwitch.Value,'On')
                    for it = 1:max(ROI(:))
                        Rho = Rho_.Value(ROI==it & isfinite(Rho_.Value));
                        Int = Int_(ROI==it & isfinite(Rho_.Value));
                        X = X_(ROI==it & isfinite(Rho_.Value));
                        Y = Y_(ROI==it & isfinite(Rho_.Value));
                        if app.PolarimetryMethod.Value==0
                            Psi = Psi_.Value(ROI==it & isfinite(Rho_.Value));
                            save([file.folder filesep file.name '_ROI' num2str(it) '.mat'],'Rho','Psi','Int','X','Y');
                        elseif app.PolarimetryMethod.Value==1 || app.PolarimetryMethod.Value==2 || app.PolarimetryMethod.Value==4
                            S2 = S2_.Value(ROI==it & isfinite(Rho_.Value));
                            S4 = S4_.Value(ROI==it & isfinite(Rho_.Value));
                            save([file.folder filesep file.name '_ROI' num2str(it) '.mat'],'Rho','S2','S4','Int','X','Y');
                        elseif app.PolarimetryMethod.Value==3
                            S_SHG = S_SHG_.Value(ROI==it & isfinite(Rho_.Value));
                            save([file.folder filesep file.name '_ROI' num2str(it) '.mat'],'Rho','S_SHG','Int','X','Y');
                        end
                    end
                else
                    Rho = Rho_.Value(ROI~=0 & isfinite(Rho_.Value));
                    Int = Int_(ROI~=0 & isfinite(Rho_.Value));
                    X = X_(ROI~=0 & isfinite(Rho_.Value));
                    Y = Y_(ROI~=0 & isfinite(Rho_.Value));
                    if app.PolarimetryMethod.Value==0
                        Psi = Psi_.Value(ROI~=0 & isfinite(Rho_.Value));
                        save([file.folder filesep file.name '.mat'],'Rho','Psi','Int','X','Y');
                    elseif app.PolarimetryMethod.Value==1 || app.PolarimetryMethod.Value==2 || app.PolarimetryMethod.Value==4
                        S2 = S2_.Value(ROI~=0 & isfinite(Rho_.Value));
                        S4 = S4_.Value(ROI~=0 & isfinite(Rho_.Value));
                        save([file.folder filesep file.name '.mat'],'Rho','S2','S4','Int','X','Y');
                    elseif app.PolarimetryMethod.Value==3
                        S_SHG = S_SHG_.Value(ROI~=0 & isfinite(Rho_.Value));
                        save([file.folder filesep file.name '.mat'],'Rho','S_SHG','Int','X','Y');
                    end
                end
            end
        end
        
        function Initialize(app)
            app.Patch.XData = [];
            app.Patch.YData = [];
            % Fluorescence & Thresholding
            app.int_roi = 0;
            app.ContrastSlider.Enable = 'on';
            app.ContrastThrshldSlider.Enable = 'on';
            app.ILowEditField.Enable = 'on';
            app.ILowImaxSlider.Enable = 'on';
            app.TransparencySlider.Enable = 'on';
            app.DetAngle.Enable = 'on';
            app.Reload.Enable = 'on';
            app.ContrastFluoButton.Enable ='on';
            app.ContrastThrshldButton.Enable = 'on';
            app.NoBackgroundButton.Enable = 'on';
            app.ExportMask.Enable = 'on';
            app.ContrastSlider.Value = 1;
            app.ILowEditField.Value = 0;
            app.ILowImaxSlider.Value = 0;
            app.UIAxesThrshld.CLim(1) = 0;
            % Advanced Options
            app.NoiseWidthEditField.Value = 3;
            app.NoiseHeightEditField.Value = 3;
            app.NoiseFactorEditField.Value = 1;
            app.NoiseremovallevelEditField.Value = 0;
        end
        
        function Represent_Thrshld(app, Itot, DrawNow, FirstDraw)
            Ithrshld = Itot/max(Itot(:));
            if strcmp(app.DropDown.Value,'Mask (manual)') || strcmp(app.DropDown.Value,'Mask (auto)')
                [~,name,~] = fileparts(app.P.filename);
                Name_Mask = [app.MaskFolder filesep name '.png'];
                if isfile(Name_Mask)
                    BinarizedIm = imread(Name_Mask);
                    MaskTh = double(BinarizedIm/max(BinarizedIm(:)));
                end
                Ithrshld = Ithrshld.*MaskTh;
            end
            if DrawNow
                if ~FirstDraw
                    XLimThrshld = app.UIAxesThrshld.XLim;
                    YLimThrshld = app.UIAxesThrshld.YLim;
                end
                colormap(app.UIAxesThrshld,app.ColormapThrshld.Value);
                im = imadjust(imsharpen(Itot/max(Itot(:))),[0 1],[0 1],app.ContrastThrshldSlider.Value);
                app.ImageThrshld = imagesc(app.UIAxesThrshld,im);
                axis(app.UIAxesThrshld,'image'); 
                if ~FirstDraw
                    app.UIAxesThrshld.XLim = XLimThrshld;
                    app.UIAxesThrshld.YLim = YLimThrshld;
                end
            end
            app.ImageThrshld.AlphaData = ones(size(Ithrshld));
            app.ImageThrshld.AlphaData(Ithrshld<=app.ILowImaxSlider.Value) = app.TransparencySlider.Value*app.ImageThrshld.AlphaData(Ithrshld<=app.ILowImaxSlider.Value);
            if app.int_roi~=0 && ~isempty(app.Patch.XData)
                for it = 1:app.int_roi
                    patch(app.UIAxesThrshld,'XData',app.Patch.XData{it},'YData',app.Patch.YData{it},'FaceAlpha',0,'EdgeColor','w');
                    text(app.UIAxesThrshld,app.Patch.XData{it}(1),app.Patch.YData{it}(1),num2str(it),'FontSize',20,'Color','w');
                end
            end
        end
        
        function ShowSaveFluo(app)
            [file.folder,file.name,~] = fileparts(app.P.filename);
            fig = figure('Name','Fluorescence Image');
            figAxes = axes(fig);
            figAxes.Colormap = app.UIAxesFluo.Colormap;
            figAxes.YDir = app.UIAxesFluo.YDir;
            copyobj(app.UIAxesFluo.XAxis.Parent.Children,figAxes);
            xlim auto, ylim auto, axis image
            if ~app.AddAxesCheckBox.Value
                set(gca,'visible','off');
            end
            title(file.name,'FontSize',14,'Interpreter','none','FontWeight','normal');
            set(findall(gca,'type','text'),'visible','on');
            drawnow
            if app.UITableDisplay.Data{4,2}
                savefig(fig,[file.folder filesep file.name '_Fluo.fig']);
            end
            if ~app.UITableDisplay.Data{4,1}
                close(fig);
            end 
        end
        
        function Dark = ComputeDark(~, Im)
            SizeCell = 20;
            [ImageWidth,ImageHeight,ImageAngle] = size(Im);
            NCellHeight = floor(ImageHeight/SizeCell);
            NCellWidth = floor(ImageWidth/SizeCell);
            Ncol = SizeCell*ones(1,NCellHeight);
            Nrow = SizeCell*ones(1,NCellWidth);
            cropIm = Im(1:SizeCell*NCellWidth,1:SizeCell*NCellHeight,1:ImageAngle);
            ImCG = mat2cell(cropIm,Nrow,Ncol,ImageAngle);
            mImCG = zeros(NCellWidth,NCellHeight);
            for it = 1:NCellWidth
                for jt = 1:NCellHeight
                    CellInd = ImCG{it,jt}(:,:,1);
                    mImCG(it,jt) = mean(CellInd(CellInd~=0));
                end
            end
            val = min(mImCG,[],'all');
            [IndI, IndJ] = find(mImCG==val);
            CellInd = ImCG{IndI,IndJ};
            Dark = mean(CellInd(CellInd~=0));
        end
        
        function [nangle, Im] = OpenFile(app, filename)
            info = imfinfo(filename);
            nangle = numel(info);
            Im = zeros(info(1).Height,info(1).Width,nangle,'double');
            TifLink = Tiff(filename,'r');
            for k = 1:nangle
               TifLink.setDirectory(k);
               Im(:,:,k) = TifLink.read();
            end
            TifLink.close();
            [~,name,~] = fileparts(filename);
            app.NameOfFile.Visible = 'on';
            app.NameOfFile.Value = name;
        end
        
        function figComposite = Composite(app, data, imbackground, orientation, file)
            if data.Display && app.UITableDisplay.Data{1,1}
                title_fig = ['Composite image of ' data.Name];
                suffix = ['_Composite' data.Name '.fig'];
                figComposite = figure('Name',title_fig);
                ax1 = axes(figComposite);
                imagesc(ax1,imbackground); 
                colormap(ax1,gray); 
                c1 = colorbar; axis image; c1.Visible = 'off';
                if ~app.AddAxesCheckBox.Value
                    set(gca,'visible','off');
                end
                ax2 = axes(figComposite);
                im = data.Value;
                if app.FigureRotationEditField.Value~=0
                    if orientation
                        im = wrapTo360(2*(im+app.FigureRotationEditField.Value))/2;
                    end
                    im = imrotate(im,app.FigureRotationEditField.Value,'nearest','crop');
                    im(im==0) = NaN;
                end
                h2 = imagesc(ax2,im,[data.Min data.Max]); 
                colormap(ax2,data.Colormap); 
                colorbar; axis image;
                alpha(h2,isfinite(im)*1);
                ax2.Visible = 'off';
                linkaxes([ax1,ax2],'xy');
                title(file.name,'Interpreter','none','FontSize',14,'FontWeight','normal');
                set(findall(gca,'type','text'),'visible','on');
                drawnow
                if app.UITableDisplay.Data{1,2}
                    savefig(figComposite,[file.folder filesep file.name suffix]);
                end
            else
                figComposite = [];
            end
        end
        
        function Sticks(app, data, imbackground, rho, orientation, file, ImageSize)
            if data.Display && app.UITableDisplay.Data{2,1}
                W = 0.2;                 % width of sticks
                L = 6;                   % length of sticks
                title_fig = [data.Name ' Sticks'];
                suffix = ['_' data.Name 'Sticks.fig'];
                figSticks = figure('Name',title_fig);
                ax1 = axes(figSticks); 
                imagesc(ax1,imbackground); 
                colormap(ax1,gray); 
                c1 = colorbar; axis image; c1.Visible = 'off'; 
                if ~app.AddAxesCheckBox.Value
                    set(gca,'visible','off');
                end
                ax2 = axes(figSticks);
                Rho_r = rho(1:app.PixelsPerStickSpinner.Value:end,1:app.PixelsPerStickSpinner.Value:end).';
                data_r = data.Value(1:app.PixelsPerStickSpinner.Value:end,1:app.PixelsPerStickSpinner.Value:end).';
                [Y,X] = meshgrid(1:app.PixelsPerStickSpinner.Value:ImageSize(1)+app.BinWidthEditField.Value-1, ...
                    1:app.PixelsPerStickSpinner.Value:ImageSize(2)+app.BinHeightEditField.Value-1);
                X = X(isfinite(Rho_r));
                Y = Y(isfinite(Rho_r));
                data_r = data_r(isfinite(Rho_r));
                Rho_r = Rho_r(isfinite(Rho_r));
                if orientation
                    data_r_color = wrapTo360(2*(data_r+app.FigureRotationEditField.Value))/2;
                else
                    data_r_color = data_r;
                end
                Vertex_r = (data_r_color.*ones(1,4)).';
                prot = patch(ax2,[X+L/2*cosd(Rho_r)+W/2*sind(Rho_r) X-L/2*cosd(Rho_r)+W/2*sind(Rho_r)...
                    X-L/2*cosd(Rho_r)-W/2*sind(Rho_r) X+L/2*cosd(Rho_r)-W/2*sind(Rho_r)].',...
                    [Y-L/2*sind(Rho_r)+W/2*cosd(Rho_r) Y+L/2*sind(Rho_r)+W/2*cosd(Rho_r)...
                    Y+L/2*sind(Rho_r)-W/2*cosd(Rho_r) Y-L/2*sind(Rho_r)-W/2*cosd(Rho_r)].',data_r.',...
                    'LineWidth',2,'EdgeAlpha',0.2,'EdgeColor','flat','FaceVertexCData',Vertex_r(:));
                rotate(prot,[0 0 1],-app.FigureRotationEditField.Value,[ImageSize(2)/2,ImageSize(1)/2,0]);
                colormap(ax2,data.Colormap);
                colorbar; 
                set(gca,'YDir','reverse');
                caxis([data.Min data.Max]);
                axis(ax2,'image'); 
                ax2.Visible = 'off'; 
                linkaxes([ax1,ax2],'xy');
                title(file.name,'Interpreter','none','FontSize',14,'FontWeight','normal');
                set(findall(gca,'type','text'),'visible','on');
                drawnow
                if app.UITableDisplay.Data{2,2}
                    savefig(figSticks,[file.folder filesep file.name suffix]);
                end
            end
        end
        
        function Histos(app, data, ROI, orientation, file)
            if data.Display && app.UITableDisplay.Data{3,1}
                if strcmp(app.perROISwitch.Value,'On')
                    for it = 1:max(ROI(:))
                        title_fig = [data.Name ' Histogram for ROI ' num2str(it)]; 
                        suffix = ['_Histo' data.Name '_perROI_' num2str(it),'.fig'];
                        figHisto = figure('Name',title_fig);
                        if orientation
                            data_vals = wrapTo360(2*(data.Value(ROI==it & isfinite(data.Value))-app.FigureRotationEditField.Value))/2;
                            avgCRho = mean(exp(-2i*deg2rad(data_vals)));
                            meanCangle = rad2deg(wrapTo2Pi(2*(pi-angle(avgCRho)/2))/2);
                            DeltaRho = wrapTo180(2*(data_vals-meanCangle))/2;
                            pax = polaraxes(figHisto);
                            polarhistogram(pax,deg2rad(wrapTo360(2*(data_vals-app.FigureRotationEditField.Value))/2),deg2rad(0:3:180));
                            pax.ThetaLim = [0 180];
                            set(gca,'FontSize',16);
                            annotation(figHisto,'textbox',[0.2 0.99 1 0],'String',file.name,'LineStyle','none','FontSize',14,'FitBoxToText','on','Interpreter','none');
                            annotation(figHisto,'textbox',[0.2 0.91 0 0],'String',['$\rho = $' num2str(wrapTo360(2*meanCangle)/2,'%.2f') ' $\pm$ ' ...
                                num2str(std(DeltaRho),'%.2f')],'LineStyle','none','FontSize',20,'FitBoxToText','on','Interpreter','latex');
                        else
                            data_vals = data.Value(ROI==it & isfinite(data.Value));
                            [Nb,Values] = histcounts(data_vals,data.Min:data.Max/60:data.Max);
                            b = bar(Values(1:end-1),Nb,'histc');
                            b.FaceColor = 'flat';
                            b.CData = linspace(1,2,length(Nb));
                            colormap(data.Colormap);
                            xlim([data.Min data.Max]); 
                            xlabel(data.LaTeX,'Interpreter','latex','FontSize',20);
                            set(gca,'FontSize',16)
                            title(file.name,'Interpreter','none','FontSize',14,'FontWeight','normal');
                            annotation(figHisto,'textbox',[0.2 0.91 0 0],'String',[data.LaTeX ' = ' num2str(mean(data_vals),'%.2f') ' $\pm$ ' ...
                                num2str(std(data_vals),'%.2f')],'LineStyle','none','FontSize',20,'FitBoxToText','on','Interpreter','latex');
                        end
                        drawnow
                        if app.UITableDisplay.Data{3,2}
                            savefig(figHisto,[file.folder filesep file.name suffix]);
                        end
                    end
                else
                    title_fig = [data.Name ' Histogram']; 
                    suffix = ['_Histo',data.Name '.fig'];
                    figHisto = figure('Name',title_fig);
                    if orientation
                        data_vals = wrapTo360(2*(data.Value(ROI~=0 & isfinite(data.Value))+app.FigureRotationEditField.Value))/2;
                        avgCRho = mean(exp(-2i*deg2rad(data_vals)));
                        meanCangle = rad2deg(wrapTo2Pi(2*(pi-angle(avgCRho)/2))/2);
                        DeltaRho = wrapTo180(2*(data_vals-meanCangle))/2;
                        pax = polaraxes(figHisto);
                        polarhistogram(pax,deg2rad(data_vals),deg2rad(0:3:180));
                        pax.ThetaLim = [0 180];
                        set(gca,'FontSize',16)
                        annotation(figHisto,'textbox',[0.2 0.99 1 0],'String',file.name,'LineStyle','none','FontSize',14,'FitBoxToText','on','Interpreter','none');
                        annotation(figHisto,'textbox',[0.2 0.91 0 0],'String',['$\rho = $' num2str(wrapTo360(2*meanCangle)/2,'%.2f') ' $\pm$ ' ...
                            num2str(std(DeltaRho),'%.2f')],'LineStyle','none','FontSize',20,'FitBoxToText','on','Interpreter','latex');
                    else
                        data_vals = data.Value(ROI~=0 & isfinite(data.Value));
                        [Nb,Values] = histcounts(data_vals,data.Min:data.Max/60:data.Max);
                        b = bar(Values(1:end-1),Nb,'histc');
                        b.FaceColor = 'flat';
                        b.CData = linspace(1,2,length(Nb));
                        colormap(data.Colormap);
                        set(gca,'FontSize',16)
                        xlim([data.Min data.Max]); 
                        xlabel(data.LaTeX,'Interpreter','latex','FontSize',20);
                        title(file.name,'Interpreter','none','FontSize',14,'FontWeight','normal');
                        annotation(figHisto,'textbox',[0.2 0.91 0 0],'String',[data.LaTeX ' $=$ ' num2str(mean(data_vals),'%.2f') ' $\pm$ ' ...
                            num2str(std(data_vals),'%.2f')],'LineStyle','none','FontSize',20,'FitBoxToText','on','Interpreter','latex');
                    end
                    drawnow
                    if app.UITableDisplay.Data{3,2}
                        savefig(figHisto,[file.folder filesep file.name suffix]);
                    end
                end
            end
        end
    end 

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            uialert(app.PolarimetryAnalysisUIFigure,{'Welcome to Polarimetry Analysis','   (1)  Select the polarimetry method',...
               '   (2)  Download a file or a folder','   (3)  Select the method of analysis','   (4)  Select one or several regions of interest',...
               '   (5)  Click on Analysis'},'Polarimetry Analysis','Icon','Icons/round_blur_circular_black_48dp.png');
            app.UITableDisplay.RowName = {'Composite','Sticks','Histogram','Fluorescence Image'};
            app.UITableDisplay.Data = {false,false;false,false;false,false;false,false};
            app.UITableVariable.RowName = {char(961),char(936)};
            app.UITableVariable.Data = {false,0,180;false,40,180};
            app.UITableSaveExtension.RowName = {'figures (.fig)','data (.mat)','mean values (.xlsx)'};
            app.UITableSaveExtension.Data = {false;false;false}; 
            app.ClickbackgroundChanged = false;
            app.UIAxesThrshld.BackgroundColor = 'k';
            load('DiskCones/Disk_Ga0_Pa20_Ta45_Gb-0.1_Pb0_Tb0_Gc-0.1_Pc0_Tc0.mat','RoTest','PsiTest','NbMapValues');
            app.NameOfDiskCone.Value = 'Disk_Ga0_Pa20_Ta45_Gb-0.1_Pb0_Tb0_Gc-0.1_Pc0_Tc0';
            app.P.RoTest = double(RoTest);
            app.P.PsiTest = double(PsiTest);
            app.P.NbMapValues = NbMapValues;
            app.UIAxesThrshld.Toolbar.Visible = 'on';
            app.UIAxesFluo.Toolbar.Visible = 'on';
        end

        % Button pushed function: PolarimetryAnalysisLogo
        function PolarimetryAnalysisLogoPushed(app, event)
            app.TabGroup.SelectedTab = app.AboutTab;
        end

        % Button pushed function: DownloadFileButton
        function DownloadFileButtonPushed(app, event)
            app.SingleFile = true;
            app.AdvanceFile = false;
            app.DropDown.Value = 'Thresholding (manual)';
            [file,app.path] = uigetfile({'*.tiff;*.tif'});
            app.TabGroup.SelectedTab = app.FluorescenceTab;
            figure(app.PolarimetryAnalysisUIFigure);
            if ~isequal(file, 0)
                Initialize(app);
                app.P.filename = [app.path file];
                [app.n_angle,app.Im] = OpenFile(app,app.P.filename);
                [app.Sum_cor,app.I_tot] = Define_I_tot(app,app.Im);
                Represent_Fluo(app,app.I_tot,true);
                Represent_Thrshld(app,app.I_tot,true,true);
                app.ROI = zeros(size(app.I_tot));
                app.ROI_ILow = zeros(size(app.I_tot));
                app.Mask = zeros(size(app.I_tot));
                app.DropDown.Enable = 'on';
                app.AddROIButton.Enable = 'on';
                app.AnalysisButton.Enable = 'on';
            end
        end

        % Button pushed function: DownloadFolderButton
        function DownloadFolderButtonPushed(app, event)
            app.SingleFile = false;
            app.DropDown.Value = 'Thresholding (manual)';
            app.Folder = uigetdir;
            app.TabGroup.SelectedTab = app.FluorescenceTab;
            figure(app.PolarimetryAnalysisUIFigure);
            if isequal(app.Folder,0) || (isempty(dir([app.Folder filesep '*.tif'])) && isempty(dir([app.Folder filesep '*.tiff'])))
                uialert(app.PolarimetryAnalysisUIFigure,'The folder does not contain .tiff or .tif files','Empty folder',...
                    'Icon','Icons/round_warning_black_48dp.png');
            else
                app.fs = matlab.io.datastore.DsFileSet(app.Folder,'FileExtensions',{'.tif','.tiff'});
                app.path = [app.Folder filesep];
                if hasfile(app.fs)
                    Initialize(app);
                    file_n = nextfile(app.fs);
                    app.P.filename = char(file_n.FileName); 
                    [app.n_angle,app.Im] = OpenFile(app,app.P.filename);
                    [app.Sum_cor,app.I_tot] = Define_I_tot(app,app.Im);  
                    Represent_Fluo(app, app.I_tot,true);
                    Represent_Thrshld(app,app.I_tot,true,true);
                    app.ROI = zeros(size(app.I_tot));
                    app.ROI_ILow = zeros(size(app.I_tot));
                    app.Mask = zeros(size(app.I_tot));
                    app.DropDown.Enable = 'on';
                    app.AddROIButton.Enable = 'on';
                    app.AnalysisButton.Enable = 'on';
                end
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            switch app.DropDown.Value
                case 'Thresholding (auto)'
                    app.AdvanceFile = true;
                case 'Thresholding (manual)'
                    app.AdvanceFile = false;
                case 'Mask (auto)'
                    app.AdvanceFile = true;
                    app.MaskFolder = uigetdir;
                    app.ILowEditField.Value = 0;
                    app.ILowImaxSlider.Value = 0;
                    app.UIAxesThrshld.CLim(1) = 0;
                    Represent_Thrshld(app,app.I_tot,false,false);
                case 'Mask (manual)'
                    app.AdvanceFile = false;
                    app.MaskFolder = uigetdir;
                    app.ILowEditField.Value = 0;
                    app.ILowImaxSlider.Value = 0;
                    app.UIAxesThrshld.CLim(1) = 0;
                    Represent_Thrshld(app,app.I_tot,false,false);
            end
        end

        % Button pushed function: AddROIButton
        function AddROIButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.ThresholdingMaskTab;
            h_roi = drawpolygon(app.UIAxesThrshld);
            selection = uiconfirm(app.PolarimetryAnalysisUIFigure,'Add ROI?','Confirm Close',...
                'Icon','Icons/round_center_focus_weak_black_48dp.png','Options',{'Yes','No'},'DefaultOption',1);
            if strcmp(selection,'Yes')
                BW = createMask(h_roi);
                app.int_roi = app.int_roi+1;
                app.ROI(BW) = app.int_roi;
                app.ROI_ILow(BW) = app.ILowEditField.Value;
                app.Patch.XData{app.int_roi} = h_roi.Position(:,1);
                app.Patch.YData{app.int_roi} = h_roi.Position(:,2);
                Represent_Fluo(app,app.I_tot,false);
                Represent_Thrshld(app,app.I_tot,false,false);
            end
            delete(h_roi);
        end

        % Button pushed function: AnalysisButton
        function AnalysisButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.FluorescenceTab;
            app.AnalysisButton.Icon = 'Icons/round_pause_circle_filled_black_48dp.png';
            pause(1/5);
            if app.int_roi == 0
                app.ROI = ones(size(app.I_tot)); 
                app.ROI_ILow = app.ILowEditField.Value*app.ROI;
                app.Mask = zeros(size(app.I_tot)); 
            end
            if app.AdvanceFile && ~isempty(app.fs)
                for it = 1:app.fs.NumFiles 
                    if app.ClickbackgroundChanged
                        app.P.Noise_tot = mean(app.I_tot(app.ClickbackgroundCoord.x1:app.ClickbackgroundCoord.x2,app.ClickbackgroundCoord.y1:app.ClickbackgroundCoord.y2),[1 2])/app.n_angle;               
                    else
                        app.P.Noise_tot = 0;
                    end
                    MaskTh = ones(size(app.I_tot));
                    if strcmp(app.DropDown.Value,'Mask (auto)')
                        [~,name,~] = fileparts(app.P.filename);
                        Name_Mask = [app.MaskFolder filesep name '.png'];
                        if isfile(Name_Mask)
                            BinarizedIm = imread(Name_Mask);
                            MaskTh = double(BinarizedIm/max(BinarizedIm(:)));
                        end
                    end
                    app.Mask = (app.I_tot>=app.ROI_ILow).*MaskTh;
                    PolarimetryAnalysis(app,app.Im,app.P,app.Mask,app.ROI);
                    if app.UITableDisplay.Data{4,1} || app.UITableDisplay.Data{4,2} 
                        ShowSaveFluo(app);
                    end
                    if hasfile(app.fs)
                        file_n = nextfile(app.fs);
                        app.P.filename = char(file_n.FileName);
                        [app.n_angle,app.Im] = OpenFile(app,app.P.filename);
                        [app.Sum_cor,app.I_tot] = Define_I_tot(app,app.Im);  
                        Represent_Fluo(app,app.I_tot,true);
                        Represent_Thrshld(app,app.I_tot,true,true);
                        pause(1/5);
                    end
                end
                uialert(app.PolarimetryAnalysisUIFigure,'End of analysis','End of analysis','Icon','Icons/round_check_circle_outline_black_48dp.png');       
                reset(app.fs);
                app.int_roi = 0;
                app.ILowEditField.Value = 0;
                app.ILowImaxSlider.Value = 0;
                app.UIAxesThrshld.CLim(1) = 0;
                app.NoiseWidthEditField.Value = 3;
                app.NoiseHeightEditField.Value = 3;
                app.NoiseFactorEditField.Value = 1;
                app.NoiseremovallevelEditField.Value = 0;
                file_n = nextfile(app.fs);
                app.P.filename = char(file_n.FileName); 
                [app.n_angle,app.Im] = OpenFile(app,app.P.filename);
                [app.Sum_cor,app.I_tot] = Define_I_tot(app,app.Im);  
                Represent_Fluo(app,app.I_tot,true);
                Represent_Thrshld(app,app.I_tot,true,true);
                app.ROI = zeros(size(app.I_tot));
                app.ROI_ILow = zeros(size(app.I_tot));
                app.Mask = zeros(size(app.I_tot));
            elseif ~app.AdvanceFile 
                if app.ClickbackgroundChanged
                    app.P.Noise_tot = mean(app.I_tot(app.ClickbackgroundCoord.x1:app.ClickbackgroundCoord.x2,app.ClickbackgroundCoord.y1:app.ClickbackgroundCoord.y2),[1 2])/app.n_angle;
                else 
                    app.P.Noise_tot = 0;
                end
                app.ClickbackgroundChanged = false;
                app.NoiseremovallevelEditField.Value = 0;
                MaskTh = ones(size(app.I_tot));
                if strcmp(app.DropDown.Value,'Mask (manual)')
                    [~,name,~] = fileparts(app.P.filename);
                    Name_Mask = [app.MaskFolder filesep name '.png'];
                    if isfile(Name_Mask)
                        BinarizedIm = imread(Name_Mask);
                        MaskTh = double(BinarizedIm/max(BinarizedIm(:)));
                    end
                end
                app.Mask = (app.I_tot>=app.ROI_ILow).*MaskTh;
                PolarimetryAnalysis(app,app.Im,app.P,app.Mask,app.ROI);
                if app.UITableDisplay.Data{4,1} || app.UITableDisplay.Data{4,2}
                    ShowSaveFluo(app);
                end
                figure(app.PolarimetryAnalysisUIFigure);
                if ~app.SingleFile 
                    if hasfile(app.fs)
                        file_n = nextfile(app.fs);
                        app.P.filename = char(file_n.FileName);
                        [app.n_angle,app.Im] = OpenFile(app,app.P.filename);
                        [app.Sum_cor,app.I_tot] = Define_I_tot(app,app.Im);
                        app.ILowEditField.Value = 0;
                        app.ILowImaxSlider.Value = 0;
                        app.UIAxesThrshld.CLim(1) = 0;
                        app.NoiseWidthEditField.Value = 3;
                        app.NoiseHeightEditField.Value = 3;
                        app.NoiseFactorEditField.Value = 1;
                        app.NoiseremovallevelEditField.Value = 0;
                    else
                        uialert(app.PolarimetryAnalysisUIFigure,'End of analysis','End of analysis',...
                            'Icon','Icons/round_check_circle_outline_black_48dp.png');
                        reset(app.fs);
                        app.int_roi = 0;
                        app.ILowEditField.Value = 0;
                        app.ILowImaxSlider.Value = 0;
                        app.UIAxesThrshld.CLim(1) = 0;
                        app.NoiseWidthEditField.Value = 3;
                        app.NoiseHeightEditField.Value = 3;
                        app.NoiseFactorEditField.Value = 1;
                        app.NoiseremovallevelEditField.Value = 0;
                        file_n = nextfile(app.fs);
                        app.P.filename = char(file_n.FileName); 
                        [app.n_angle,app.Im] = OpenFile(app,app.P.filename);
                        [app.Sum_cor,app.I_tot] = Define_I_tot(app,app.Im);  
                        Represent_Fluo(app,app.I_tot,true);
                        Represent_Thrshld(app,app.I_tot,true,true);
                        app.ROI = zeros(size(app.I_tot));
                        app.ROI_ILow = zeros(size(app.I_tot));
                        app.Mask = zeros(size(app.I_tot));
                    end
                end
                app.Patch.XData = [];
                app.Patch.YData = [];
                Represent_Fluo(app,app.I_tot,true);
                Represent_Thrshld(app,app.I_tot,true,true);
                app.int_roi = 0;
                app.ROI = zeros(size(app.I_tot));
                app.ROI_ILow = zeros(size(app.I_tot));
                app.Mask = zeros(size(app.I_tot)); 
                if app.SingleFile
                    app.DropDown.Value = 'Thresholding (manual)'; 
                    app.ILowEditField.Value = 0;
                    app.ILowImaxSlider.Value = 0;
                    app.UIAxesThrshld.CLim(1) = 0;
                    app.NoiseWidthEditField.Value = 3;
                    app.NoiseHeightEditField.Value = 3;
                    app.NoiseFactorEditField.Value = 1;
                    app.NoiseremovallevelEditField.Value = 0;
                    Represent_Fluo(app,app.I_tot,true);
                    Represent_Thrshld(app,app.I_tot,true,true);
                end
            end 
            app.AnalysisButton.Icon = 'Icons/round_play_circle_filled_black_48dp.png';
        end

        % Button pushed function: CloseFiguresButton
        function CloseFiguresButtonPushed(app, event)
            close all
        end

        % Value changed function: ContrastSlider
        function ContrastSliderValueChanged(app, event)
            if app.ContrastSlider.Value<=0.001
                app.ContrastSlider.Value = 0.001;
            end
            Represent_Fluo(app,app.I_tot,false);
        end

        % Button pushed function: DetAngle
        function DetAngleButtonPushed(app, event)
            hline = drawline(app.UIAxesFluo);
            slope = atand((hline.Position(2,2)-hline.Position(1,2))/(hline.Position(2,1)-hline.Position(1,1)));
            slope = wrapTo360(2*slope)/2;
            uialert(app.PolarimetryAnalysisUIFigure,['Angle = ' num2str(slope) ' (deg)'],'Value of the angle',...
                'Icon','Icons/round_square_foot_black_48dp.png');
            delete(hline);
        end

        % Value changed function: ILowEditField
        function ILowEditFieldValueChanged(app, event)
            if app.ILowEditField.Value>=0.999*max(app.I_tot(:))
                app.ILowEditField.Value = 0.999*max(app.I_tot(:));
            end
            app.ILowImaxSlider.Value = app.ILowEditField.Value/max(app.I_tot(:));
            Represent_Thrshld(app,app.I_tot,false,false);
        end

        % Value changed function: ILowImaxSlider
        function ILowImaxSliderValueChanged(app, event)
            if app.ILowImaxSlider.Value>=0.999
                app.ILowImaxSlider.Value = 0.999;
            end
            app.ILowEditField.Value = round(max(app.I_tot(:))*app.ILowImaxSlider.Value);
            Represent_Thrshld(app,app.I_tot,false,false);
        end

        % Value changed function: TransparencySlider
        function TransparencySliderValueChanged(app, event)
            if app.TransparencySlider.Value<=0.001
                app.TransparencySlider.Value = 0.001;
            end
            Represent_Thrshld(app,app.I_tot,false,false);
        end

        % Button pushed function: ExportMask
        function ExportMaskButtonPushed(app, event)
            [filepath, name, ~] = fileparts(app.P.filename);
            selection = uiconfirm(app.PolarimetryAnalysisUIFigure,'Create Mask by ROI or by Intensity', 'Create Mask',...
               'Options',{'ROI','Intensity','ROI x Intensity','Cancel'},'DefaultOption',1,'CancelOption',3, ...
               'Icon','Icons/round_open_in_new_black_48dp.png');
            if strcmp(selection,'ROI')
                A = app.ROI;
                A(app.ROI~=0) = 1;
                imwrite(A,[filepath filesep name '_mask.png']);
            elseif strcmp(selection,'Intensity')
                A = zeros(size(app.I_tot));
                A(app.I_tot>=app.ILowEditField.Value) = 1;
                imwrite(A,[filepath filesep name '_mask.png']);
            elseif strcmp(selection,'ROI x Intensity')
                A = zeros(size(app.I_tot));
                A(app.I_tot>=app.ROI_ILow) = 1;
                imwrite(A.*app.ROI,[filepath filesep name '_mask.png']);
            end
        end

        % Button pushed function: NoBackgroundButton
        function NoBackgroundButtonPushed(app, event)
            if app.ThresholdingMaskTab.BackgroundColor(1) == 0
                app.ThresholdingMaskTab.BackgroundColor = [0.65 0.65 0.65];
                app.ContrastThrshldSlider.FontColor = [0 0 0];
                app.TransparencySlider.FontColor = [0 0 0];
                app.ILowImaxSlider.FontColor = [0 0 0];
                app.NoBackgroundButton.Icon = "Icons/outline_insert_photo_black_48dp.png";
            else
                app.ThresholdingMaskTab.BackgroundColor = [0 0 0];
                app.ContrastThrshldSlider.FontColor = [1 1 1];
                app.TransparencySlider.FontColor = [1 1 1];
                app.ILowImaxSlider.FontColor = [1 1 1];
                app.NoBackgroundButton.Icon = "Icons/round_image_black_48dp.png";
            end 
            Represent_Thrshld(app,app.I_tot,false,false);
        end

        % Cell edit callback: UITableSaveExtension
        function UITableSaveExtensionCellEdit(app, event)
            if ~app.UITableSaveExtension.Data{1}
                app.UITableDisplay.Data(cell2mat(app.UITableDisplay.Data(:,2))==1,2)={false};
            end
            if app.UITableSaveExtension.Data{1} && ~any(cell2mat(app.UITableDisplay.Data(:,2)))
                uialert(app.PolarimetryAnalysisUIFigure,'Select the type of figures to be saved (Display table, Save column)',...
                    'Polarimetry Analysis','Icon','Icons/round_add_photo_alternate_black_48dp.png');
            end
        end

        % Button pushed function: DeleteAllButton
        function DeleteAllButtonPushed(app, event)
            app.UITableDisplay.Data(:,1) = {false};
            app.UITableDisplay.Data(:,2) = {false};
            app.UITableVariable.Data(:,1) = {false};
            app.UITableSaveExtension.Data{1} = false;
        end

        % Value changed function: ShowIndividualFitCheckBox
        function ShowIndividualFitCheckBoxValueChanged(app, event)
            if app.ShowIndividualFitCheckBox.Value 
                app.UITableDisplay.Data{1,1} = true;
                app.UITableVariable.Data{1,1} = true;
            else
                app.UITableDisplay.Data{1,1} = false;
                app.UITableVariable.Data{1,1} = false;
            end
        end

        % Value changed function: CalculatedDarkValueCheckBox
        function CalculatedDarkValueCheckBoxValueChanged(app, event)
            if app.CalculatedDarkValueCheckBox.Value
                app.UserDarkValueEditField.Enable = 'off';
                app.CalculatedDarkValueEditField.Visible = 'on';
            elseif ~app.CalculatedDarkValueCheckBox.Value
                app.UserDarkValueEditField.Enable = 'on';
            end
        end

        % Button pushed function: ClickBackgroundButton
        function ClickBackgroundButtonPushed(app, event)
            app.ClickbackgroundChanged = true;
            if ~isfield(app.P,'filename')
                uialert(app.PolarimetryAnalysisUIFigure,'Download a file or a folder','Polarimetry Analysis', ...
                    'Icon','Icons/round_add_photo_alternate_black_48dp.png');
            else    
                app.TabGroup.SelectedTab = app.FluorescenceTab;
                app.PolarimetryAnalysisUIFigure.HandleVisibility = 'on';
                [y,x] = ginput(1);
                app.PolarimetryAnalysisUIFigure.HandleVisibility = 'off';
                if ~isempty(x) && ~isempty(y)
                    app.ClickbackgroundCoord.x1 = round(x)-(app.NoiseHeightEditField.Value-1)/2;
                    app.ClickbackgroundCoord.x2 = round(x)+(app.NoiseHeightEditField.Value-1)/2;
                    app.ClickbackgroundCoord.y1 = round(y)-(app.NoiseWidthEditField.Value-1)/2;
                    app.ClickbackgroundCoord.y2 = round(y)+(app.NoiseWidthEditField.Value-1)/2;
                    app.NoiseremovallevelEditField.Value = mean(app.I_tot(app.ClickbackgroundCoord.x1:app.ClickbackgroundCoord.x2,app.ClickbackgroundCoord.y1:app.ClickbackgroundCoord.y2),[1 2])...
                        /app.n_angle*app.NoiseFactorEditField.Value;
                end
                app.TabGroup.SelectedTab = app.AdvancedTab;
            end
        end

        % Value changed function: BinWidthEditField
        function BinWidthEditFieldValueChanged(app, event)
            BIN = ones(app.BinWidthEditField.Value,app.BinHeightEditField.Value);                                                     
            app.I_tot = convn(app.Sum_cor,BIN)/app.BinWidthEditField.Value/app.BinHeightEditField.Value;
            app.ROI = zeros(size(app.I_tot));
            app.ROI_ILow = zeros(size(app.I_tot));
            app.Mask = zeros(size(app.I_tot));
            app.Patch.XData = [];
            app.Patch.YData = [];
            app.int_roi = 0;
            Represent_Fluo(app,app.I_tot,false);
            Represent_Thrshld(app,app.I_tot,true,false);
        end

        % Value changed function: BinHeightEditField
        function BinHeightEditFieldValueChanged(app, event)
            BIN = ones(app.BinWidthEditField.Value,app.BinHeightEditField.Value);                                                     
            app.I_tot = convn(app.Sum_cor,BIN)/app.BinWidthEditField.Value/app.BinHeightEditField.Value;
            app.ROI = zeros(size(app.I_tot));
            app.ROI_ILow = zeros(size(app.I_tot));
            app.Mask = zeros(size(app.I_tot));
            app.Patch.XData = [];
            app.Patch.YData = [];
            app.int_roi = 0;
            Represent_Fluo(app,app.I_tot,false);
            Represent_Thrshld(app,app.I_tot,true,false);
        end

        % Button pushed function: DisplayConeButton
        function DisplayConeButtonPushed(app, event)
            figure('Name',['Disk Cone : ' app.NameOfDiskCone.Value]);
            subplot(1,2,1);
            imshow(app.P.RoTest,jet); axis image; title('Rho Test');
            set(gca,'visible','on');
            subplot(1,2,2)
            imshow(app.P.PsiTest,jet); axis image; title('Psi Test');   
            set(gca,'visible','on');
        end

        % Value changed function: FigureRotationEditField
        function FigureRotationEditFieldValueChanged(app, event)
            Represent_Fluo(app,app.I_tot,false);
        end

        % Callback function
        function PolarimetryAnalysisAboutPushed(app, event)
            app.TabGroup.SelectedTab = app.FluorescenceTab;
        end

        % Value changed function: ColormapThrshld
        function ColormapThrshldValueChanged(app, event)
            if ~isempty(app.I_tot)
                Represent_Thrshld(app,app.I_tot,true,false);
            end
        end

        % Value changed function: ContrastThrshldSlider
        function ContrastThrshldSliderValueChanged(app, event)
            if app.ContrastThrshldSlider.Value<=0.001
                app.ContrastThrshldSlider.Value = 0.001;
            end
            Represent_Thrshld(app,app.I_tot,true,false);
        end

        % Value changed function: SelectDiskCone
        function SelectDiskConeValueChanged(app, event)
            filepath = ['DiskCones' filesep];
            if strcmp(app.SelectDiskCone.Value,'1')
                name = 'Disk_Ga0_Pa20_Ta45_Gb-0.1_Pb0_Tb0_Gc-0.1_Pc0_Tc0';
            elseif strcmp(app.SelectDiskCone.Value,'2')
                name = 'Disk_Ga-0.2_Pa0_Ta0_Gb0.1_Pb0_Tb0_Gc-0.2_Pc0_Tc0';
            elseif strcmp(app.SelectDiskCone.Value,'3')
                name = 'Disk_Ga-0.2_Pa0_Ta45_Gb0.1_Pb0_Tb45_Gc-0.1_Pc0_Tc0';
            elseif strcmp(app.SelectDiskCone.Value,'4')
                name = 'Disk_Ga-0.1_Pa20_Ta0_Gb-0.1_Pb20_Tb45_Gc-0.2_Pc0_Tc0';
            elseif strcmp(app.SelectDiskCone.Value,'5')
                name = 'Disk_Ga-0.2_Pa0_Ta0_Gb0.2_Pb20_Tb0_Gc-0.2_Pc0_Tc0';
            elseif strcmp(app.SelectDiskCone.Value,'6')
                name = 'Disk_Ga-0.1_Pa20_Ta0_Gb-0.1_Pb10_Tb45_Gc-0.2_Pc0_Tc0';
            elseif strcmp(app.SelectDiskCone.Value,'7')
                name = 'Disk_Ga-0.1_Pa20_Ta0_Gb-0.1_Pb10_Tb45_Gc0.1_Pc0_Tc0';
            elseif strcmp(app.SelectDiskCone.Value,'8')
                name = 'Disk_Ga0.1_Pa0_Ta45_Gb-0.1_Pb20_Tb0_Gc-0.1_Pc0_Tc0';
            elseif strcmp(app.SelectDiskCone.Value,'9')
                name = 'Disk_Ga-0.1_Pa10_Ta0_Gb0.1_Pb30_Tb0_Gc0.2_Pc0_Tc0';
            elseif strcmp(app.SelectDiskCone.Value,'10')
                name = 'Disk_Ga0_Pa0_Ta0_Gb0_Pb0_Tb0_Gc0_Pc0_Tc0';
            elseif strcmp(app.SelectDiskCone.Value,'11')
                [file,filepath] = uigetfile('Disk*.mat');
                [~,name,~] = fileparts([filepath file]);
            end
            load([filepath name '.mat'],'RoTest','PsiTest','NbMapValues');
            app.NameOfDiskCone.Value = name;
            app.P.RoTest = double(RoTest);
            app.P.PsiTest = double(PsiTest);
            app.P.NbMapValues = NbMapValues;
        end

        % Button pushed function: CheckWebsite
        function CheckWebsiteButtonPushed(app, event)
            web('https://www.fresnel.fr/polarimetry','-browser')
        end

        % Button pushed function: Reload
        function ReloadButtonPushed(app, event)
            app.int_roi = 0;
            app.Patch.XData = [];
            app.Patch.YData = [];
            Represent_Fluo(app,app.I_tot,true);
            Represent_Thrshld(app,app.I_tot,true,true);
            app.ROI = zeros(size(app.I_tot));
            app.ROI_ILow = zeros(size(app.I_tot));
            app.Mask = zeros(size(app.I_tot));
        end

        % Value changed function: PolarimetryMethod
        function PolarimetryMethodValueChanged(app, event)
            if app.PolarimetryMethod.Value==0
                app.UITableVariable.RowName = {char(961),char(936)};
                app.UITableVariable.Data = {false,0,180;false,40,180};
                app.DiskConePanel.Visible = 'on';
                app.UITableVariable.Position(4) = 73;
                app.UITableVariable.ColumnFormat{2} = 'short';
                app.UITableVariable.ColumnFormat{3} = 'short';
            elseif app.PolarimetryMethod.Value==1 || app.PolarimetryMethod.Value==2 || app.PolarimetryMethod.Value==4
                app.UITableVariable.RowName = {char(961),'S2','S4'};
                app.UITableVariable.Data = {false,0,180;false,0,1;false,-1,1};
                app.DiskConePanel.Visible = 'off';
                app.UITableVariable.Position(4) = 95;
                app.UITableVariable.ColumnFormat{2} = 'bank';
                app.UITableVariable.ColumnFormat{3} = 'bank';
            elseif app.PolarimetryMethod.Value==3
                S_SHG = 'S_SHG';
                app.UITableVariable.RowName = {char(961),S_SHG}; 
                app.UITableVariable.Data = {false,0,180;false,-1,1};
                app.DiskConePanel.Visible = 'off';
                app.UITableVariable.Position(4) = 73;
                app.UITableVariable.ColumnFormat{2} = 'bank';
                app.UITableVariable.ColumnFormat{3} = 'bank';
            end
        end

        % Button pushed function: ContrastThrshldButton
        function ContrastThrshldButtonPushed(app, event)
            if app.ContrastThrshldSlider.Value<=0.5
                app.ContrastThrshldSlider.Value = 0.5;
                Represent_Thrshld(app,app.I_tot,true,false);
            else
                app.ContrastThrshldSlider.Value = 1;
                Represent_Thrshld(app,app.I_tot,true,false);
            end
        end

        % Button pushed function: ContrastFluoButton
        function ContrastFluoButtonPushed(app, event)
            if app.ContrastSlider.Value<=0.5
                app.ContrastSlider.Value = 0.5;
                Represent_Fluo(app,app.I_tot,false);
            else
                app.ContrastSlider.Value = 1;
                Represent_Fluo(app,app.I_tot,false);
            end
        end

        % Display data changed function: UITableDisplay
        function UITableDisplayDisplayDataChanged(app, event)
            if any(cell2mat(app.UITableDisplay.Data(:,2)))
                app.UITableSaveExtension.Data(1) = {true};
            else
                app.UITableSaveExtension.Data(1) = {false};
            end
            app.UITableDisplay.Data(cell2mat(app.UITableDisplay.Data(:,2))==1,1) = {true};
        end

        % Value changed function: Switchminmax
        function SwitchminmaxValueChanged(app, event)
            if strcmp(app.Switchminmax.Value,'On')
                app.UITableVariable.ColumnEditable(2) = true;
                app.UITableVariable.ColumnEditable(3) = true;
            else
                app.UITableVariable.ColumnEditable(2) = false;
                app.UITableVariable.ColumnEditable(3) = false;
            end
        end

        % Value changed function: Switchoffset
        function SwitchoffsetValueChanged(app, event)
            if strcmp(app.Switchoffset.Value,'On')
                app.OffsetAngleDegEditField.Editable = true;
            else
                app.OffsetAngleDegEditField.Editable = false;
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.PolarimetryAnalysisUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {572, 572};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {173, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.PolarimetryAnalysisUIFigure = uifigure('Visible', 'off');
            app.PolarimetryAnalysisUIFigure.Name = 'Polarimetry Analysis';
            app.PolarimetryAnalysisUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.PolarimetryAnalysisUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DownloadFileButton = uibutton(app.LeftPanel, 'push');
            app.DownloadFileButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadFileButtonPushed, true);
            app.DownloadFileButton.Icon = fullfile(pathToMLAPP, 'Icons', 'round_add_photo_alternate_black_48dp.png');
            app.DownloadFileButton.Text = 'Download file ';
            app.AddROIButton = uibutton(app.LeftPanel, 'push');
            app.AddROIButton.ButtonPushedFcn = createCallbackFcn(app, @AddROIButtonPushed, true);
            app.AddROIButton.Icon = fullfile(pathToMLAPP, 'Icons', 'round_center_focus_weak_black_48dp.png');
            app.AddROIButton.Enable = 'off';
            app.AddROIButton.Tooltip = {'Add a region of interest'};
            app.AddROIButton.Text = 'Add ROI';
            app.AnalysisButton = uibutton(app.LeftPanel, 'push');
            app.AnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @AnalysisButtonPushed, true);
            app.AnalysisButton.Icon = fullfile(pathToMLAPP, 'Icons', 'round_play_circle_filled_black_48dp.png');
            app.AnalysisButton.Enable = 'off';
            app.AnalysisButton.Tooltip = {'Polarimetry analysis'};
            app.AnalysisButton.Text = 'Analysis';
            app.DownloadFolderButton = uibutton(app.LeftPanel, 'push');
            app.DownloadFolderButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadFolderButtonPushed, true);
            app.DownloadFolderButton.Icon = fullfile(pathToMLAPP, 'Icons', 'round_create_new_folder_black_48dp.png');
            app.DownloadFolderButton.Text = 'Download folder';
            app.DropDown = uidropdown(app.LeftPanel);
            app.DropDown.Items = {'Thresholding (manual)', 'Thresholding (auto)', 'Mask (manual)', 'Mask (auto)'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Enable = 'off';
            app.DropDown.Tooltip = {'Select the method of analysis: intensity thresholding or segmentation mask, for single file analysis ("manual") or batch processing ("auto"). The mask has to be binary and in a png format and have the same file name as the respective polarimetry data file.'};
            app.DropDown.Value = 'Thresholding (manual)';
            app.PolarimetryAnalysisLogo = uibutton(app.LeftPanel, 'push');
            app.PolarimetryAnalysisLogo.ButtonPushedFcn = createCallbackFcn(app, @PolarimetryAnalysisLogoPushed, true);
            app.PolarimetryAnalysisLogo.Icon = fullfile(pathToMLAPP, 'Icons', 'round_blur_circular_black_48dp.png');
            app.PolarimetryAnalysisLogo.Text = {'POLARIMETRY '; 'ANALYSIS'};
            app.CloseFiguresButton = uibutton(app.LeftPanel, 'push');
            app.CloseFiguresButton.ButtonPushedFcn = createCallbackFcn(app, @CloseFiguresButtonPushed, true);
            app.CloseFiguresButton.Icon = fullfile(pathToMLAPP, 'Icons', 'round_close_black_48dp.png');
            app.CloseFiguresButton.Tooltip = {'Close all .fig files'};
            app.CloseFiguresButton.Text = 'Close figures';
            app.PolarimetryMethod = uidropdown(app.LeftPanel);
            app.PolarimetryMethod.Items = {'1PF', 'CARS', 'SRS', 'SHG', '2PF'};
            app.PolarimetryMethod.ItemsData = [0 1 2 3 4];
            app.PolarimetryMethod.ValueChangedFcn = createCallbackFcn(app, @PolarimetryMethodValueChanged, true);
            app.PolarimetryMethod.Value = 0;
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Icons', 'round_biotech_black_48dp.png');
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.FluorescenceTab = uitab(app.TabGroup);
            app.FluorescenceTab.Title = 'Fluorescence';
            app.UIAxesFluo = uiaxes(app.FluorescenceTab);
            app.UIAxesFluo.XTick = [];
            app.UIAxesFluo.YTick = [];
            app.UIAxesFluo.Visible = 'off';
            app.ContrastSlider = uislider(app.FluorescenceTab);
            app.ContrastSlider.Limits = [0 1];
            app.ContrastSlider.MajorTicks = [0 0.5 1];
            app.ContrastSlider.MajorTickLabels = {'0', '0.5', '1'};
            app.ContrastSlider.ValueChangedFcn = createCallbackFcn(app, @ContrastSliderValueChanged, true);
            app.ContrastSlider.Enable = 'off';
            app.ContrastSlider.Tooltip = {''};
            app.ContrastSlider.Value = 1;
            app.MaximumIntensityEditFieldLabel = uilabel(app.FluorescenceTab);
            app.MaximumIntensityEditFieldLabel.Text = 'Maximum Intensity';
            app.MaximumIntensityEditField = uieditfield(app.FluorescenceTab, 'numeric');
            app.MaximumIntensityEditField.Limits = [0 Inf];
            app.MaximumIntensityEditField.RoundFractionalValues = 'on';
            app.MaximumIntensityEditField.ValueDisplayFormat = '%.0f';
            app.MaximumIntensityEditField.Editable = 'off';
            app.MaximumIntensityEditField.Enable = 'off';
            app.DetAngle = uibutton(app.FluorescenceTab, 'push');
            app.DetAngle.ButtonPushedFcn = createCallbackFcn(app, @DetAngleButtonPushed, true);
            app.DetAngle.Icon = fullfile(pathToMLAPP, 'Icons', 'round_square_foot_black_48dp.png');
            app.DetAngle.Enable = 'off';
            app.DetAngle.Tooltip = {'Left click and hold to trace a line segment and determine its angle'};
            app.DetAngle.Text = '';
            app.ContrastFluoButton = uibutton(app.FluorescenceTab, 'push');
            app.ContrastFluoButton.ButtonPushedFcn = createCallbackFcn(app, @ContrastFluoButtonPushed, true);
            app.ContrastFluoButton.Icon = fullfile(pathToMLAPP, 'Icons', 'round_brightness_6_black_48dp.png');
            app.ContrastFluoButton.Enable = 'off';
            app.ContrastFluoButton.Tooltip = {'The chosen contrast will be the one used for the fluorescence images in figures'};
            app.ContrastFluoButton.Text = '';
            app.NameOfFile = uieditfield(app.FluorescenceTab, 'text');
            app.NameOfFile.Editable = 'off';
            app.NameOfFile.Visible = 'off';
            app.NameOfFile.Tooltip = {''};
            app.Reload = uibutton(app.FluorescenceTab, 'push');
            app.Reload.ButtonPushedFcn = createCallbackFcn(app, @ReloadButtonPushed, true);
            app.Reload.Icon = fullfile(pathToMLAPP, 'Icons', 'baseline_restart_alt_black_48dp.png');
            app.Reload.Enable = 'off';
            app.Reload.Tooltip = {'Erase ROIs and reload image'};
            app.Reload.Text = '';
            app.ThresholdingMaskTab = uitab(app.TabGroup);
            app.ThresholdingMaskTab.Title = 'Thresholding/Mask';
            app.UIAxesThrshld = uiaxes(app.ThresholdingMaskTab);
            app.UIAxesThrshld.XTick = [];
            app.UIAxesThrshld.YTick = [];
            app.UIAxesThrshld.Visible = 'off';
            app.ILowEditFieldLabel = uilabel(app.ThresholdingMaskTab);
            app.ILowEditFieldLabel.Text = 'ILow';
            app.ILowEditField = uieditfield(app.ThresholdingMaskTab, 'numeric');
            app.ILowEditField.Limits = [0 Inf];
            app.ILowEditField.ValueDisplayFormat = '%.0f';
            app.ILowEditField.ValueChangedFcn = createCallbackFcn(app, @ILowEditFieldValueChanged, true);
            app.ILowEditField.Enable = 'off';
            app.ILowImaxLabel = uilabel(app.ThresholdingMaskTab);
            app.ILowImaxLabel.Text = 'ILow/Imax';
            app.ILowImaxSlider = uislider(app.ThresholdingMaskTab);
            app.ILowImaxSlider.Limits = [0 1];
            app.ILowImaxSlider.MajorTicks = [0 0.25 0.5 0.75 1];
            app.ILowImaxSlider.MajorTickLabels = {'0', '0.25', '0.5', '0.75', '1'};
            app.ILowImaxSlider.ValueChangedFcn = createCallbackFcn(app, @ILowImaxSliderValueChanged, true);
            app.ILowImaxSlider.Enable = 'off';
            app.TransparencySlider = uislider(app.ThresholdingMaskTab);
            app.TransparencySlider.Limits = [0 1];
            app.TransparencySlider.MajorTicks = [0 0.5 1];
            app.TransparencySlider.ValueChangedFcn = createCallbackFcn(app, @TransparencySliderValueChanged, true);
            app.TransparencySlider.Enable = 'off';
            app.TransparencySlider.Tooltip = {'adjust the transparency of the background image'};
            app.TransparencyLabel = uilabel(app.ThresholdingMaskTab);
            app.TransparencyLabel.Text = 'Transparency';
            app.NoBackgroundButton = uibutton(app.ThresholdingMaskTab, 'push');
            app.NoBackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @NoBackgroundButtonPushed, true);
            app.NoBackgroundButton.Icon = fullfile(pathToMLAPP, 'Icons', 'round_image_black_48dp.png');
            app.NoBackgroundButton.Enable = 'off';
            app.NoBackgroundButton.Tooltip = {'Change background to enhance visibility'};
            app.NoBackgroundButton.Text = '';
            app.ExportMask = uibutton(app.ThresholdingMaskTab, 'push');
            app.ExportMask.ButtonPushedFcn = createCallbackFcn(app, @ExportMaskButtonPushed, true);
            app.ExportMask.Icon = fullfile(pathToMLAPP, 'Icons', 'round_open_in_new_black_48dp.png');
            app.ExportMask.Enable = 'off';
            app.ExportMask.Tooltip = {'Export mask as .png '};
            app.ExportMask.Text = '';
            app.ContrastThrshldSlider = uislider(app.ThresholdingMaskTab);
            app.ContrastThrshldSlider.Limits = [0 1];
            app.ContrastThrshldSlider.MajorTicks = [0 0.5 1];
            app.ContrastThrshldSlider.MajorTickLabels = {'0', '0.5', '1'};
            app.ContrastThrshldSlider.ValueChangedFcn = createCallbackFcn(app, @ContrastThrshldSliderValueChanged, true);
            app.ContrastThrshldSlider.Enable = 'off';
            app.ContrastThrshldSlider.Tooltip = {''};
            app.ContrastThrshldSlider.Value = 1;
            app.ContrastThrshldButton = uibutton(app.ThresholdingMaskTab, 'push');
            app.ContrastThrshldButton.ButtonPushedFcn = createCallbackFcn(app, @ContrastThrshldButtonPushed, true);
            app.ContrastThrshldButton.Icon = fullfile(pathToMLAPP, 'Icons', 'round_brightness_6_black_48dp.png');
            app.ContrastThrshldButton.Enable = 'off';
            app.ContrastThrshldButton.Tooltip = {''};
            app.ContrastThrshldButton.Text = '';
            app.OptionsTab = uitab(app.TabGroup);
            app.OptionsTab.Title = 'Options';
            app.UITableSaveExtension = uitable(app.OptionsTab);
            app.UITableSaveExtension.ColumnName = {'Save extension'};
            app.UITableSaveExtension.RowName = {};
            app.UITableSaveExtension.ColumnEditable = true;
            app.UITableSaveExtension.CellEditCallback = createCallbackFcn(app, @UITableSaveExtensionCellEdit, true);
            app.perROILabel = uilabel(app.OptionsTab);
            app.perROILabel.Text = 'per ROI:';
            app.perROISwitch = uiswitch(app.OptionsTab, 'slider');
            app.perROISwitch.Tooltip = {'show and save data/figures separately for each region of interest'};
            app.DeleteAllButton = uibutton(app.OptionsTab, 'push');
            app.DeleteAllButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteAllButtonPushed, true);
            app.DeleteAllButton.Icon = fullfile(pathToMLAPP, 'Icons', 'round_delete_forever_black_48dp.png');
            app.DeleteAllButton.Tooltip = {'Reinitialize the tables Show/Save and Variable'};
            app.DeleteAllButton.Text = '';
            app.UITableDisplay = uitable(app.OptionsTab);
            app.UITableDisplay.ColumnName = {'Show'; 'Save'};
            app.UITableDisplay.RowName = {};
            app.UITableDisplay.ColumnEditable = [true true];
            app.UITableDisplay.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDisplayDataChanged, true);
            app.UITableVariable = uitable(app.OptionsTab);
            app.UITableVariable.ColumnName = {'Variable'; 'Min'; 'Max'};
            app.UITableVariable.RowName = {};
            app.UITableVariable.ColumnEditable = [true false false];
            app.PlotoptionsPanel = uipanel(app.OptionsTab);
            app.PlotoptionsPanel.Title = 'Plot options';
            app.AddAxesCheckBox = uicheckbox(app.PlotoptionsPanel);
            app.AddAxesCheckBox.Text = 'Add axes on figures';
            app.AddAxesCheckBox.Value = true;
            app.pixelsperstickLabel = uilabel(app.PlotoptionsPanel);
            app.pixelsperstickLabel.Text = '^2 pixels per stick';
            app.PixelsPerStickSpinner = uispinner(app.PlotoptionsPanel);
            app.PixelsPerStickSpinner.Limits = [1 100];
            app.PixelsPerStickSpinner.ValueDisplayFormat = '%.0f';
            app.PixelsPerStickSpinner.Editable = 'off';
            app.PixelsPerStickSpinner.Tooltip = {'controls the density of sticks to be plotted '};
            app.PixelsPerStickSpinner.Value = 1;
            app.ColormapThresholdingDropDownLabel = uilabel(app.PlotoptionsPanel);
            app.ColormapThresholdingDropDownLabel.Text = 'Colormap (Thresholding)';
            app.ColormapThrshld = uidropdown(app.PlotoptionsPanel);
            app.ColormapThrshld.Items = {'hot', 'gray'};
            app.ColormapThrshld.ValueChangedFcn = createCallbackFcn(app, @ColormapThrshldValueChanged, true);
            app.ColormapThrshld.Value = 'hot';
            app.ShowIndividualFitCheckBox = uicheckbox(app.PlotoptionsPanel);
            app.ShowIndividualFitCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowIndividualFitCheckBoxValueChanged, true);
            app.ShowIndividualFitCheckBox.Text = 'Show individual fit';
            app.SwitchminmaxLabel = uilabel(app.OptionsTab);
            app.SwitchminmaxLabel.Text = 'Switch min/max';
            app.Switchminmax = uiswitch(app.OptionsTab, 'slider');
            app.Switchminmax.ValueChangedFcn = createCallbackFcn(app, @SwitchminmaxValueChanged, true);
            app.AdvancedTab = uitab(app.TabGroup);
            app.AdvancedTab.Title = 'Advanced';
            app.BinningPanel = uipanel(app.AdvancedTab);
            app.BinningPanel.Title = 'Binning';
            app.BinwidthEditFieldLabel = uilabel(app.BinningPanel);
            app.BinwidthEditFieldLabel.Text = 'Bin width';
            app.BinWidthEditField = uieditfield(app.BinningPanel, 'numeric');
            app.BinWidthEditField.Limits = [1 Inf];
            app.BinWidthEditField.ValueDisplayFormat = '%.0f';
            app.BinWidthEditField.ValueChangedFcn = createCallbackFcn(app, @BinWidthEditFieldValueChanged, true);
            app.BinWidthEditField.Value = 1;
            app.BinheightEditFieldLabel = uilabel(app.BinningPanel);
            app.BinheightEditFieldLabel.Text = 'Bin height';
            app.BinHeightEditField = uieditfield(app.BinningPanel, 'numeric');
            app.BinHeightEditField.Limits = [1 Inf];
            app.BinHeightEditField.ValueDisplayFormat = '%.0f';
            app.BinHeightEditField.ValueChangedFcn = createCallbackFcn(app, @BinHeightEditFieldValueChanged, true);
            app.BinHeightEditField.Value = 1;
            app.RemovebackgroundPanel = uipanel(app.AdvancedTab);
            app.RemovebackgroundPanel.Title = 'Remove background';
            app.NoisefactorEditFieldLabel = uilabel(app.RemovebackgroundPanel);
            app.NoisefactorEditFieldLabel.Text = 'Noise factor';
            app.NoiseFactorEditField = uieditfield(app.RemovebackgroundPanel, 'numeric');
            app.NoiseFactorEditField.Limits = [0 1];
            app.NoiseFactorEditField.Value = 1;
            app.ClickBackgroundButton = uibutton(app.RemovebackgroundPanel, 'push');
            app.ClickBackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @ClickBackgroundButtonPushed, true);
            app.ClickBackgroundButton.Icon = fullfile(pathToMLAPP, 'Icons', 'round_exposure_black_48dp.png');
            app.ClickBackgroundButton.Tooltip = {'Click and select a point on the fluorescence image'};
            app.ClickBackgroundButton.Text = 'Click background';
            app.NoisewidthEditFieldLabel = uilabel(app.RemovebackgroundPanel);
            app.NoisewidthEditFieldLabel.Text = 'Noise width';
            app.NoiseWidthEditField = uieditfield(app.RemovebackgroundPanel, 'numeric');
            app.NoiseWidthEditField.Limits = [1 Inf];
            app.NoiseWidthEditField.ValueDisplayFormat = '%.0f';
            app.NoiseWidthEditField.Value = 3;
            app.NoiseheightEditFieldLabel = uilabel(app.RemovebackgroundPanel);
            app.NoiseheightEditFieldLabel.Text = 'Noise height';
            app.NoiseHeightEditField = uieditfield(app.RemovebackgroundPanel, 'numeric');
            app.NoiseHeightEditField.Limits = [1 Inf];
            app.NoiseHeightEditField.ValueDisplayFormat = '%.0f';
            app.NoiseHeightEditField.Value = 3;
            app.NoiseremovallevelEditFieldLabel = uilabel(app.RemovebackgroundPanel);
            app.NoiseremovallevelEditFieldLabel.Text = 'Noise removal level';
            app.NoiseremovallevelEditField = uieditfield(app.RemovebackgroundPanel, 'numeric');
            app.NoiseremovallevelEditField.ValueDisplayFormat = '%.0f';
            app.NoiseremovallevelEditField.Enable = 'off';
            app.OffsetangledegEditFieldLabel = uilabel(app.AdvancedTab);
            app.OffsetangledegEditFieldLabel.Text = 'Offset angle (deg)';
            app.OffsetAngleDegEditField = uieditfield(app.AdvancedTab, 'numeric');
            app.OffsetAngleDegEditField.Limits = [0 180];
            app.OffsetAngleDegEditField.ValueDisplayFormat = '%.0f';
            app.OffsetAngleDegEditField.Editable = 'off';
            app.OffsetAngleDegEditField.Value = 100;
            app.RotationPanel = uipanel(app.AdvancedTab);
            app.RotationPanel.Title = 'Rotation';
            app.StickLabel = uilabel(app.RotationPanel);
            app.StickLabel.Text = 'Stick (deg)';
            app.StickRotationEditField = uieditfield(app.RotationPanel, 'numeric');
            app.StickRotationEditField.Limits = [0 180];
            app.StickRotationEditField.ValueDisplayFormat = '%.0f';
            app.FigureLabel = uilabel(app.RotationPanel);
            app.FigureLabel.Text = 'Figure (deg)';
            app.FigureRotationEditField = uieditfield(app.RotationPanel, 'numeric');
            app.FigureRotationEditField.Limits = [-180 180];
            app.FigureRotationEditField.ValueDisplayFormat = '%.0f';
            app.FigureRotationEditField.ValueChangedFcn = createCallbackFcn(app, @FigureRotationEditFieldValueChanged, true);
            app.FigureRotationEditField.Tooltip = {'positive value for clockwise rotation / negative value for counter-clockwise rotation'};
            app.DiskConePanel = uipanel(app.AdvancedTab);
            app.DiskConePanel.Title = 'Disk Cone';
            app.DisplayConeButton = uibutton(app.DiskConePanel, 'push');
            app.DisplayConeButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayConeButtonPushed, true);
            app.DisplayConeButton.Icon = fullfile(pathToMLAPP, 'Icons', 'round_image_black_48dp.png');
            app.DisplayConeButton.Tooltip = {'Display the selected disk cone'};
            app.DisplayConeButton.Text = 'Display';
            app.NameOfDiskCone = uieditfield(app.DiskConePanel, 'text');
            app.NameOfDiskCone.Editable = 'off';
            app.NameOfDiskCone.Enable = 'off';
            app.SelectDiskCone = uidropdown(app.DiskConePanel);
            app.SelectDiskCone.Items = {'488 nm ', '561 nm', '640 nm', '488 nm (13/12/2019 - 15/03/2020)', '561 nm (13/12/2019 - 15/03/2020)', '640 nm (13/12/2019 - 15/03/2020)', '488 nm (before 13/12/2019)', '561 nm (before 13/12/2019)', '640 nm (before 13/12/2019)', 'no distortions', 'other'};
            app.SelectDiskCone.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11'};
            app.SelectDiskCone.ValueChangedFcn = createCallbackFcn(app, @SelectDiskConeValueChanged, true);
            app.SelectDiskCone.Tooltip = {'Select disk cone depending on wavelength and acquisition date'};
            app.SelectDiskCone.Value = '1';
            app.DarkPanel = uipanel(app.AdvancedTab);
            app.DarkPanel.Title = 'Dark';
            app.CalculatedDarkValueCheckBox = uicheckbox(app.DarkPanel);
            app.CalculatedDarkValueCheckBox.ValueChangedFcn = createCallbackFcn(app, @CalculatedDarkValueCheckBoxValueChanged, true);
            app.CalculatedDarkValueCheckBox.Text = 'Calculated dark value';
            app.CalculatedDarkValueCheckBox.Value = true;
            app.UserdarkvalueEditFieldLabel = uilabel(app.DarkPanel);
            app.UserdarkvalueEditFieldLabel.Text = 'User dark value ';
            app.UserDarkValueEditField = uieditfield(app.DarkPanel, 'numeric');
            app.UserDarkValueEditField.Limits = [480 Inf];
            app.UserDarkValueEditField.ValueDisplayFormat = '%.0f';
            app.UserDarkValueEditField.Enable = 'off';
            app.UserDarkValueEditField.Value = 480;
            app.CalculatedDarkValueEditField = uieditfield(app.DarkPanel, 'numeric');
            app.CalculatedDarkValueEditField.Limits = [0 Inf];
            app.CalculatedDarkValueEditField.ValueDisplayFormat = '%.0f';
            app.CalculatedDarkValueEditField.Editable = 'off';
            app.CalculatedDarkValueEditField.Enable = 'off';
            app.CalculatedDarkValueEditField.Value = 480;
            app.SwitchoffsetLabel = uilabel(app.AdvancedTab);
            app.SwitchoffsetLabel.Text = 'Switch offset';
            app.Switchoffset = uiswitch(app.AdvancedTab, 'slider');
            app.Switchoffset.ValueChangedFcn = createCallbackFcn(app, @SwitchoffsetValueChanged, true);
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.InfoAbout = uitextarea(app.AboutTab);
            app.InfoAbout.Editable = 'off';
            app.InfoAbout.Value = {'POLARIMETRY ANALYSIS'; ''; 'Version: 2.0.1 (April 7, 2022)  created with App Designer - MATLAB R2022a'; ''; 'Source code available at github.com/cchandre/Polarimetry '; ''; 'Website: www.fresnel.fr/polarimetry/'; ''; 'Based on a code originally developed by Sophie Brasselet (Institut Fresnel, CNRS)'; ''; 'To report bugs, send an email to '; '            manos.mavrakis@cnrs.fr  (Manos Mavrakis, Institut Fresnel, CNRS) '; '            cristel.chandre@cnrs.fr  (Cristel Chandre, Institut de Mathématiques de Marseille, CNRS)'; ''; 'Copyright (C) 2019 by Cristel Chandre <cristel.chandre@cnrs.fr>'; ''; 'Permission to use, copy, modify, and/or distribute this software for any purpose with or without fee is hereby granted.'; ''; 'THE SOFTWARE IS PROVIDED "AS IS" AND THE AUTHORS DISCLAIM ALL WARRANTIES WITH REGARD TO THIS SOFTWARE INCLUDING ALL IMPLIED WARRANTIES OF MERCHANTABILITY AND FITNESS. IN NO EVENT SHALL THE AUTHORS BE LIABLE FOR ANY SPECIAL, DIRECT, INDIRECT, OR CONSEQUENTIAL DAMAGES OR ANY DAMAGES WHATSOEVER RESULTING FROM LOSS OF USE, DATA OR PROFITS, WHETHER IN AN ACTION OF CONTRACT, NEGLIGENCE OR OTHER TORTIOUS ACTION, ARISING OUT OF OR IN CONNECTION WITH THE USE OR PERFORMANCE OF THIS SOFTWARE.'; ''; ''; 'uses Material Design icons by Google'};
            app.CheckWebsite = uibutton(app.AboutTab, 'push');
            app.CheckWebsite.ButtonPushedFcn = createCallbackFcn(app, @CheckWebsiteButtonPushed, true);
            app.CheckWebsite.Icon = fullfile(pathToMLAPP, 'Icons', 'round_web_black_48dp.png');
            app.CheckWebsite.Text = 'CHECK WEBSITE FOR UPDATES';
            % Show the figure after all components are created
            app.PolarimetryAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PolarimetryPlus

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PolarimetryAnalysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PolarimetryAnalysisUIFigure)
        end
    end
end